classdef plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MessageDropDownLabel   matlab.ui.control.Label
        MessageDropDown        matlab.ui.control.DropDown
        OpenLogButton          matlab.ui.control.Button
        AircraftDropDownLabel  matlab.ui.control.Label
        AircraftDropDown       matlab.ui.control.DropDown
        MessageListBox         matlab.ui.control.ListBox
        Plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        p % Parselog data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenLogButton
        function OpenLogButtonPushed(app, event)
            [file,path] = uigetfile('*.log');
            if isequal(file,0)
               disp('User selected Cancel');
               return
            else
               disp(['User selected ', fullfile(path,file)]);
            end
            
            % Parse the log
            app.p = parselog(fullfile(path,file));
            
            % Check if 1 or multiple aircrafts
            if size(app.p.aircrafts, 1) > 1
                ac_names = [];
                for i = 1:size(app.p.aircrafts, 1)
                    ac_names(i) = app.p.aircrafts(i).name;
                end
                app.AircraftDropDown.Items = ac_names;
                app.AircraftDropDown.Enable
                app.AircraftDropDownLabel.Enable
            else
                % Show the aircraft name
                app.AircraftDropDown.Items = [app.p.aircrafts.name];
                
                % Show the message fields
                app.MessageDropDown.Items = fieldnames(app.p.aircrafts.data);
                app.MessageDropDown.Enable = 'on';
                app.MessageDropDownLabel.Enable = 'on';
                
                % Show the first items
                names = fieldnames(app.p.aircrafts.data.(app.MessageDropDown.Value));
                app.MessageListBox.Items = names(2:end);
                app.MessageListBox.Enable = 'on';
            end
        end

        % Value changed function: MessageDropDown
        function MessageDropDownValueChanged(app, event)
            % Show the message items
            names = fieldnames(app.p.aircrafts.data.(app.MessageDropDown.Value));
            app.MessageListBox.Items = names(2:end);
            
            % Try to show a plot
            plot_fnc = string(strcat('plot_', lower(app.MessageDropDown.Value)));
            disp(['User func ', plot_fnc]);
            if exist(plot_fnc)
                disp(['Plotting...']);
                figure()
                feval(plot_fnc, app.p.aircrafts.data)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MessageDropDownLabel = uilabel(app.UIFigure);
            app.MessageDropDownLabel.Enable = 'off';
            app.MessageDropDownLabel.Text = 'Message';
            app.MessageDropDown = uidropdown(app.UIFigure);
            app.MessageDropDown.Items = {};
            app.MessageDropDown.ValueChangedFcn = createCallbackFcn(app, @MessageDropDownValueChanged, true);
            app.MessageDropDown.Enable = 'off';
            app.MessageDropDown.Value = {};
            app.OpenLogButton = uibutton(app.UIFigure, 'push');
            app.OpenLogButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLogButtonPushed, true);
            app.OpenLogButton.Text = 'Open Log';
            app.AircraftDropDownLabel = uilabel(app.UIFigure);
            app.AircraftDropDownLabel.Enable = 'off';
            app.AircraftDropDownLabel.Text = 'Aircraft';
            app.AircraftDropDown = uidropdown(app.UIFigure);
            app.AircraftDropDown.Items = {};
            app.AircraftDropDown.Enable = 'off';
            app.AircraftDropDown.Value = {};
            app.MessageListBox = uilistbox(app.UIFigure);
            app.MessageListBox.Items = {};
            app.MessageListBox.Multiselect = 'on';
            app.MessageListBox.Enable = 'off';
            app.MessageListBox.Value = {};
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end